classdef processC3D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PrepareC3DfileUIFigure          matlab.ui.Figure
        Selectc3dfileButton             matlab.ui.control.Button
        UIAxesSteps                     matlab.ui.control.UIAxes
        PrepareC3DfileforOpenSimsimulationsLabel  matlab.ui.control.Label
        SelectaC3DfileLabel             matlab.ui.control.Label
        detectforceplatesautomaticallyCheckBox  matlab.ui.control.CheckBox
        filterGRFsCheckBox              matlab.ui.control.CheckBox
        setGRFstozerooutsideregionoffootcontactCheckBox  matlab.ui.control.CheckBox
        detectwalkingdirectionautomaticallybymarkerCheckBox  matlab.ui.control.CheckBox
        rotatearoundyaxisDropDownLabel  matlab.ui.control.Label
        rotatearoundyaxisDropDown       matlab.ui.control.DropDown
        CreatefilesButton               matlab.ui.control.Button
        leftListBoxLabel                matlab.ui.control.Label
        leftListBox                     matlab.ui.control.ListBox
        correctfootstrikeeventsLabel    matlab.ui.control.Label
        rightListBoxLabel               matlab.ui.control.Label
        rightListBox                    matlab.ui.control.ListBox
        openselectedfilewithdefaultprogramButton  matlab.ui.control.Button
        LogTextAreaLabel                matlab.ui.control.Label
        LogTextArea                     matlab.ui.control.TextArea
        UIAxesGRFs                      matlab.ui.control.UIAxes
        filterorderSpinnerLabel         matlab.ui.control.Label
        filterorderSpinner              matlab.ui.control.Spinner
        cutofffrequencySpinnerLabel     matlab.ui.control.Label
        cutofffrequencySpinner          matlab.ui.control.Spinner
        detectWalkingByMarker           matlab.ui.control.EditField
        detectFPbyMarker                matlab.ui.control.EditField
    end

    
    properties (Access = private)
        c3dFileName % Description
        path % Description
        rightFootStrikeList % Description
        leftFootStrikeList % Description
        c3d % Description
        grf_forces % Description
        markers % Description
        rotationAngle % Description
        firstFrame % Description
        leftStepForcePlateAssignment % Description
        rightStepForcePlateAssignment % Description
        acq % Description
    end
    
    methods (Access = private)
        
        function writeLog(app, msg)
            app.LogTextArea.Value = [app.LogTextArea.Value; msg];
            drawnow; % Force text area to update
        end
        
        function updateCyclePlot(app)
            cla(app.UIAxesSteps);
            
            selectedLeft = app.leftListBox.Value;
            for i = 1 : size(app.leftFootStrikeList, 2) - 1
                if ismember(app.leftFootStrikeList{i}, selectedLeft)
                    fill(app.UIAxesSteps, [str2num(app.leftFootStrikeList{i}) str2num(app.leftFootStrikeList{i}) str2num(app.leftFootStrikeList{i + 1})], [0 1 0], 'r', 'FaceAlpha', 1, 'EdgeColor', "none");
                else
                    fill(app.UIAxesSteps, [str2num(app.leftFootStrikeList{i}) str2num(app.leftFootStrikeList{i}) str2num(app.leftFootStrikeList{i + 1})], [0 1 0], 'k', 'FaceAlpha', 0.1, 'EdgeColor', "none");
                end
            end
            selectedRight = app.rightListBox.Value;
            for i = 1 : size(app.rightFootStrikeList, 2) - 1
                if ismember(app.rightFootStrikeList{i}, selectedRight)
                    fill(app.UIAxesSteps, [str2num(app.rightFootStrikeList{i}) str2num(app.rightFootStrikeList{i}) str2num(app.rightFootStrikeList{i + 1})], [-1 0 0], 'g', 'FaceAlpha', 1, 'EdgeColor', "none");
                else
                    fill(app.UIAxesSteps, [str2num(app.rightFootStrikeList{i}) str2num(app.rightFootStrikeList{i}) str2num(app.rightFootStrikeList{i + 1})], [-1 0 0], 'k', 'FaceAlpha', 0.1, 'EdgeColor', "none");
                end
            end
            
            writeFPtoCyclePlot(app);
        end
        
        function writeFPtoCyclePlot(app)
            for i = 1 : size(app.leftStepForcePlateAssignment, 1)
                text(app.UIAxesSteps, app.leftStepForcePlateAssignment(i, 1), 0.5, ['FP' num2str(app.leftStepForcePlateAssignment(i, 2))]);
            end
            for i = 1 : size(app.rightStepForcePlateAssignment, 1)
                text(app.UIAxesSteps, app.rightStepForcePlateAssignment(i, 1), -0.5, ['FP' num2str(app.rightStepForcePlateAssignment(i, 2))]);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % add OpenSim jar file to java path
            addingSuccessfull = 0;
            systemPaths = strsplit(getenv('PATH'), ';');
            
            for i = 1 : numel(systemPaths)
                if contains(systemPaths{i}, 'OpenSim')
                    systemPaths{i} = strrep(systemPaths{i}, '"', '');
                    pathToAdd = systemPaths{i};
                    javaaddpath(pathToAdd);
                    addingSuccessfull = 1;
                    break;
                end
            end
            
            if ~addingSuccessfull
                errordlg('Check if you have installed OpenSim properly and added it to System PATH variable. Typically the installation path is C:\OpenSim 4.2\bin');
                app.delete;
            end 
            
            hold(app.UIAxesSteps, 'on');
            hold(app.UIAxesGRFs, 'on');
        end

        % Button pushed function: Selectc3dfileButton
        function Selectc3dfileButtonPushed(app, event)
            [app.c3dFileName, app.path] = uigetfile('*.c3d', 'MultiSelect', 'off', 'Select C3D file of trial');
            app.SelectaC3DfileLabel.Text = fullfile(app.path, app.c3dFileName);
            
            app.openselectedfilewithdefaultprogramButton.Visible = 1;
            
            % get events and put them in the list for selection
            app.acq = btkReadAcquisition(fullfile(app.path, app.c3dFileName));
            c3devents = btkGetEvents(app.acq);
            frequency = btkGetPointFrequency(app.acq);
            app.firstFrame = btkGetFirstFrame(app.acq);
            
            if isfield(c3devents, 'Left_Foot_Strike')
                app.leftFootStrikeList = sprintfc('%.0f', c3devents.Left_Foot_Strike * frequency);
                app.leftListBox.Items = app.leftFootStrikeList(1 : end-1);
                app.leftListBox.Value = app.leftFootStrikeList(1 : end-1);
            end
            if isfield(c3devents, 'Right_Foot_Strike')
                app.rightFootStrikeList = sprintfc('%.0f', c3devents.Right_Foot_Strike * frequency);
                app.rightListBox.Items = app.rightFootStrikeList(1 : end-1);
                app.rightListBox.Value = app.rightFootStrikeList(1 : end-1);
            end
            
            offsetFrames = 10;
            
            xminlimit = min(c3devents.Right_Foot_Strike(1), c3devents.Left_Foot_Strike(1)) * frequency - offsetFrames;
            xmaxlimit = max(c3devents.Right_Foot_Strike(end), c3devents.Left_Foot_Strike(end)) * frequency + offsetFrames;
            xlim(app.UIAxesSteps, [xminlimit xmaxlimit]);
            ylim(app.UIAxesSteps, [-1.1 1.1]);
            
            c3dpath = fullfile(app.path, app.c3dFileName);
            app.c3d = osimC3D(c3dpath, 1);
            app.c3d.rotateData('x', -90);
            app.c3d.convertMillimeters2Meters();
            app.grf_forces = osimTableToStruct(app.c3d.getTable_forces);
            app.markers = osimTableToStruct(app.c3d.getTable_markers);
            
            forceOffset = app.firstFrame / frequency * app.c3d.getRate_force();
            xminlimit = min(c3devents.Right_Foot_Strike(1), c3devents.Left_Foot_Strike(1)) * app.c3d.getRate_force() - offsetFrames / frequency * app.c3d.getRate_force() - forceOffset;
            xmaxlimit = max(c3devents.Right_Foot_Strike(end), c3devents.Left_Foot_Strike(end)) * app.c3d.getRate_force() + offsetFrames / frequency * app.c3d.getRate_force() - forceOffset;
            
            xlim(app.UIAxesGRFs, [xminlimit xmaxlimit]);
            
            detectwalkingdirectionautomaticallybymarkerCheckBoxValueChanged(app, 0);
            detectforceplatesautomaticallyCheckBoxValueChanged(app, 0);
            
            updateGRF_Plot(app, 0);
            updateCyclePlot(app);
            
            writeLog(app, [app.c3dFileName ' sucessfully read']);
        end

        % Callback function: detectWalkingByMarker, 
        % detectWalkingByMarker, 
        % detectwalkingdirectionautomaticallybymarkerCheckBox
        function detectwalkingdirectionautomaticallybymarkerCheckBoxValueChanged(app, event)
            value = app.detectwalkingdirectionautomaticallybymarkerCheckBox.Value;
            app.rotatearoundyaxisDropDown.Enable = mod(value + 1, 2);
            
            if value % detect walking direction and 
                if isfield(app.markers, app.detectWalkingByMarker.Value)
                    app.detectWalkingByMarker.BackgroundColor = 'white';
                    app.detectWalkingByMarker.FontColor = 'black';
                    markerToTrack = app.detectWalkingByMarker.Value;
                    
                    distanceTravelled = app.markers.(markerToTrack)(end, :) - app.markers.(markerToTrack)(1, :);
                    [~, maxInd] = max(abs(distanceTravelled));
                    if maxInd == 3
                        if distanceTravelled(maxInd) < 0
                            app.rotatearoundyaxisDropDown.Value = '270°';
                            app.rotationAngle = 270;
                        else
                            app.rotatearoundyaxisDropDown.Value = '90°';
                            app.rotationAngle = 90;
                        end
                    elseif maxInd == 1
                        if distanceTravelled(maxInd) < 0
                            app.rotatearoundyaxisDropDown.Value = '180°';
                            app.rotationAngle = 180;
                        else
                            app.rotatearoundyaxisDropDown.Value = '0°';
                            app.rotationAngle = 0;
                        end                        
                    end
                    
                else
                    app.detectWalkingByMarker.Value = 'SET THIS!';
                    app.detectWalkingByMarker.BackgroundColor = 'red';
                    app.detectWalkingByMarker.FontColor = 'white';
                end
            end
        end

        % Button pushed function: CreatefilesButton
        function CreatefilesButtonPushed(app, event)
            [folder, baseFileNameNoExt, ~] = fileparts(fullfile(app.path, app.c3dFileName));
            output_folder = fullfile(folder, baseFileNameNoExt);
                        
            % create folder with the same name as c3d file
            if ~exist(output_folder, 'dir')
                mkdir(output_folder)
            end
            
            app.c3d.rotateData('y', app.rotationAngle);
            app.c3d.writeTRC(fullfile(output_folder, 'marker_experimental.trc'));
            
            nForces = app.c3d.getNumForces();
            
            if (nForces ~= 0)
                grfFileName = fullfile(output_folder, 'grf.mot');
                app.c3d.writeMOT(grfFileName);
                    
                %replace nan and -nan(ind)
                fid  = fopen(grfFileName,'r');
                f=fread(fid,'*char')';
                fclose(fid);
                f = strrep(f,'-nan(ind)','0');
                f = strrep(f,'nan','0');
                fid  = fopen(grfFileName,'w');
                fprintf(fid,'%s',f);
                fclose(fid);
                
            
                % open it again and apply filter
                forces = load_sto_file(grfFileName);
                forcesFields = fieldnames(forces);
            
                Fs = app.c3d.getRate_force();  % Sampling Frequency
                N  = app.filterorderSpinner.Value;  % Filter Order
                Fc = app.cutofffrequencySpinner.Value;  % Cutoff Frequency
                [filter_b, filter_a] = butter(N,Fc/(Fs/2), 'low');
                %%
                forces = convertStruct(forces);
                for i = 1 : numel(forcesFields)
                   if char(forcesFields(i)) ~= "time"
                       forcesStruct_filtered.(char(forcesFields(i))) = filtfilt(filter_b, filter_a, forces.(char(forcesFields(i))));
                   else
                       % do not filter time
                       forcesStruct_filtered.(char(forcesFields(i))) = forces.(char(forcesFields(i)));
                   end
                end
            
                % write out the filtered data to the mot file again
                delete(grfFileName);
                write_sto_file(forcesStruct_filtered, grfFileName);
            end
                       
            copyfile(folder, fullfile(output_folder, 'c3dfile.c3d'));
            
            grforces = xml_read('GRF_file_all.xml');
            grforces_generated = xml_read('GRF_file_empty.xml');
            counter = 1;
            for i = 1 : size(app.rightStepForcePlateAssignment, 1)
                grforces_generated.ExternalLoads.objects.ExternalForce(counter) = grforces.ExternalLoads.objects.ExternalForce(app.rightStepForcePlateAssignment(i, 2));
                counter = counter + 1;
            end
            for i = 1 : size(app.leftStepForcePlateAssignment, 1)
                grforces_generated.ExternalLoads.objects.ExternalForce(counter) = grforces.ExternalLoads.objects.ExternalForce(5 + app.leftStepForcePlateAssignment(i, 2));
                counter = counter + 1;
            end
            Pref = struct;
            Pref.StructItem = false;
        
            xml_write(fullfile(output_folder, 'GRF.xml'), grforces_generated, 'OpenSimDocument', Pref);
            
            cycle = struct;
            cycle.left = struct;
            cycle.left.start = str2double(app.leftListBox.Value) - app.firstFrame;
            footStrikes = str2double(app.leftFootStrikeList) - app.firstFrame;
            for i = 1 : size(cycle.left.start, 2)
                cycle.left.end(i) = footStrikes( find( footStrikes > cycle.left.start(i), 1 ) );
            end
            
            cycle.right.footStrike = str2double(app.rightFootStrikeList);
            
            
        end

        % Button pushed function: 
        % openselectedfilewithdefaultprogramButton
        function openselectedfilewithdefaultprogramButtonPushed(app, event)
            winopen(fullfile(app.path, app.c3dFileName));
        end

        % Value changed function: leftListBox, rightListBox
        function updateCycles(app, event)
            updateCyclePlot(app);
        end

        % Value changed function: cutofffrequencySpinner, 
        % filterGRFsCheckBox, filterorderSpinner
        function updateGRF_Plot(app, event)
            cla(app.UIAxesGRFs);
            
            for i = 1 : (numel(fieldnames(app.grf_forces)) - 1) / 3
                plot(app.UIAxesGRFs, app.grf_forces.(['f' num2str(i)])(:, 2));
            end
            
            if app.filterGRFsCheckBox.Value
                Fs = app.c3d.getRate_force();  % Sampling Frequency
                N  = app.filterorderSpinner.Value;  % Filter Order
                Fc = app.cutofffrequencySpinner.Value;  % Cutoff Frequency
                [filter_b, filter_a] = butter(N,Fc/(Fs/2), 'low');
                
                for i = 1 : (numel(fieldnames(app.grf_forces)) - 1) / 3
                    plot(app.UIAxesGRFs, filtfilt(filter_b, filter_a, app.grf_forces.(['f' num2str(i)])(:, 2)));
                end
            end
        end

        % Value changed function: 
        % detectforceplatesautomaticallyCheckBox
        function detectforceplatesautomaticallyCheckBoxValueChanged(app, event)
            if app.detectforceplatesautomaticallyCheckBox.Value
                app.c3d.rotateData('x', 90);
                temp_markers = osimTableToStruct(app.c3d.getTable_markers);
                forceplateMaxXCoordinate = 400;
                app.leftStepForcePlateAssignment = [];
                app.rightStepForcePlateAssignment = [];
                [forceplates, ~] = btkGetForcePlatforms(app.acq);
                
                for i = 1 : size(app.leftFootStrikeList, 2) - 1
                    % find closest forceplate to heel marker
                    heelLocation = temp_markers.(app.detectFPbyMarker.Value)(str2num(app.leftFootStrikeList{i}) - app.firstFrame, :);
                    if heelLocation(1) > 0 && heelLocation(1) < forceplateMaxXCoordinate
                        for j = 1 : 5
                            if heelLocation(2) > forceplates(j).corners(2,1) && heelLocation(2) < forceplates(j).corners(2,3)
                                break;
                            end
                        end                        
                        app.leftStepForcePlateAssignment(end+1, 1) = str2num(app.leftFootStrikeList{i});
                        app.leftStepForcePlateAssignment(end, 2) = j;
                    end 
                end
                for i = 1 : size(app.rightFootStrikeList, 2) - 1
                    % find closest forceplate to heel marker
                    heelLocation = temp_markers.(strrep(app.detectFPbyMarker.Value, 'L', 'R'))(str2num(app.rightFootStrikeList{i}) - app.firstFrame, :);
                    if heelLocation(1) > 0 && heelLocation(1) < forceplateMaxXCoordinate
                        for j = 1 : 5
                            if heelLocation(2) > forceplates(j).corners(2,1) && heelLocation(2) < forceplates(j).corners(2,3)
                                break;
                            end
                        end                        
                        app.rightStepForcePlateAssignment(end+1, 1) = str2num(app.rightFootStrikeList{i});
                        app.rightStepForcePlateAssignment(end, 2) = j;
                    end 
                end
                
            app.c3d.rotateData('x', -90);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PrepareC3DfileUIFigure = uifigure('Visible', 'off');
            app.PrepareC3DfileUIFigure.Name = 'Prepare C3D file';
            app.Selectc3dfileButton = uibutton(app.PrepareC3DfileUIFigure, 'push');
            app.Selectc3dfileButton.ButtonPushedFcn = createCallbackFcn(app, @Selectc3dfileButtonPushed, true);
            app.Selectc3dfileButton.Text = 'Select c3d file';
            app.UIAxesSteps = uiaxes(app.PrepareC3DfileUIFigure);
            title(app.UIAxesSteps, 'Steps')
            xlabel(app.UIAxesSteps, '')
            ylabel(app.UIAxesSteps, '')
            app.PrepareC3DfileforOpenSimsimulationsLabel = uilabel(app.PrepareC3DfileUIFigure);
            app.PrepareC3DfileforOpenSimsimulationsLabel.Text = {'Prepare C3D file for OpenSim simulations'; 'creating marker_experimental.trc / grf.mot / grf.xml / settings.mat and a copy of original c3d-file'};
            app.SelectaC3DfileLabel = uilabel(app.PrepareC3DfileUIFigure);
            app.SelectaC3DfileLabel.Text = 'Select a C3D file';
            app.detectforceplatesautomaticallyCheckBox = uicheckbox(app.PrepareC3DfileUIFigure);
            app.detectforceplatesautomaticallyCheckBox.ValueChangedFcn = createCallbackFcn(app, @detectforceplatesautomaticallyCheckBoxValueChanged, true);
            app.detectforceplatesautomaticallyCheckBox.Text = 'detect forceplates automatically';
            app.detectforceplatesautomaticallyCheckBox.Value = true;
            app.filterGRFsCheckBox = uicheckbox(app.PrepareC3DfileUIFigure);
            app.filterGRFsCheckBox.ValueChangedFcn = createCallbackFcn(app, @updateGRF_Plot, true);
            app.filterGRFsCheckBox.Text = 'filter GRFs';
            app.filterGRFsCheckBox.Value = true;
            app.setGRFstozerooutsideregionoffootcontactCheckBox = uicheckbox(app.PrepareC3DfileUIFigure);
            app.setGRFstozerooutsideregionoffootcontactCheckBox.Text = 'set GRFs to zero outside region of foot contact';
            app.setGRFstozerooutsideregionoffootcontactCheckBox.Value = true;
            app.detectwalkingdirectionautomaticallybymarkerCheckBox = uicheckbox(app.PrepareC3DfileUIFigure);
            app.detectwalkingdirectionautomaticallybymarkerCheckBox.ValueChangedFcn = createCallbackFcn(app, @detectwalkingdirectionautomaticallybymarkerCheckBoxValueChanged, true);
            app.detectwalkingdirectionautomaticallybymarkerCheckBox.Text = 'detect walking direction automatically by marker';
            app.detectwalkingdirectionautomaticallybymarkerCheckBox.Value = true;
            app.rotatearoundyaxisDropDownLabel = uilabel(app.PrepareC3DfileUIFigure);
            app.rotatearoundyaxisDropDownLabel.Enable = 'off';
            app.rotatearoundyaxisDropDownLabel.Text = 'rotate around y axis';
            app.rotatearoundyaxisDropDown = uidropdown(app.PrepareC3DfileUIFigure);
            app.rotatearoundyaxisDropDown.Items = {'0°', '90°', '180°', '270°'};
            app.rotatearoundyaxisDropDown.Enable = 'off';
            app.rotatearoundyaxisDropDown.Value = '0°';
            app.CreatefilesButton = uibutton(app.PrepareC3DfileUIFigure, 'push');
            app.CreatefilesButton.ButtonPushedFcn = createCallbackFcn(app, @CreatefilesButtonPushed, true);
            app.CreatefilesButton.Text = 'Create files';
            app.leftListBoxLabel = uilabel(app.PrepareC3DfileUIFigure);
            app.leftListBoxLabel.Text = 'left ';
            app.leftListBox = uilistbox(app.PrepareC3DfileUIFigure);
            app.leftListBox.Items = {};
            app.leftListBox.Multiselect = 'on';
            app.leftListBox.ValueChangedFcn = createCallbackFcn(app, @updateCycles, true);
            app.leftListBox.Value = {};
            app.correctfootstrikeeventsLabel = uilabel(app.PrepareC3DfileUIFigure);
            app.correctfootstrikeeventsLabel.Text = 'correct foot strike events';
            app.rightListBoxLabel = uilabel(app.PrepareC3DfileUIFigure);
            app.rightListBoxLabel.Text = 'right';
            app.rightListBox = uilistbox(app.PrepareC3DfileUIFigure);
            app.rightListBox.Items = {};
            app.rightListBox.Multiselect = 'on';
            app.rightListBox.ValueChangedFcn = createCallbackFcn(app, @updateCycles, true);
            app.rightListBox.Value = {};
            app.openselectedfilewithdefaultprogramButton = uibutton(app.PrepareC3DfileUIFigure, 'push');
            app.openselectedfilewithdefaultprogramButton.ButtonPushedFcn = createCallbackFcn(app, @openselectedfilewithdefaultprogramButtonPushed, true);
            app.openselectedfilewithdefaultprogramButton.Visible = 'off';
            app.openselectedfilewithdefaultprogramButton.Text = 'open selected file with default program';
            app.LogTextAreaLabel = uilabel(app.PrepareC3DfileUIFigure);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.PrepareC3DfileUIFigure);
            app.LogTextArea.Editable = 'off';
            app.UIAxesGRFs = uiaxes(app.PrepareC3DfileUIFigure);
            title(app.UIAxesGRFs, 'GRFs')
            xlabel(app.UIAxesGRFs, 'frame')
            ylabel(app.UIAxesGRFs, '')
            app.filterorderSpinnerLabel = uilabel(app.PrepareC3DfileUIFigure);
            app.filterorderSpinnerLabel.Text = 'filter order';
            app.filterorderSpinner = uispinner(app.PrepareC3DfileUIFigure);
            app.filterorderSpinner.Limits = [1 6];
            app.filterorderSpinner.ValueChangedFcn = createCallbackFcn(app, @updateGRF_Plot, true);
            app.filterorderSpinner.Value = 1;
            app.cutofffrequencySpinnerLabel = uilabel(app.PrepareC3DfileUIFigure);
            app.cutofffrequencySpinnerLabel.Text = 'cutoff frequency';
            app.cutofffrequencySpinner = uispinner(app.PrepareC3DfileUIFigure);
            app.cutofffrequencySpinner.Limits = [4 100];
            app.cutofffrequencySpinner.ValueChangedFcn = createCallbackFcn(app, @updateGRF_Plot, true);
            app.cutofffrequencySpinner.Value = 40;
            app.detectWalkingByMarker = uieditfield(app.PrepareC3DfileUIFigure, 'text');
            app.detectWalkingByMarker.ValueChangedFcn = createCallbackFcn(app, @detectwalkingdirectionautomaticallybymarkerCheckBoxValueChanged, true);
            app.detectWalkingByMarker.ValueChangingFcn = createCallbackFcn(app, @detectwalkingdirectionautomaticallybymarkerCheckBoxValueChanged, true);
            app.detectWalkingByMarker.Value = 'LHEE';
            app.detectFPbyMarker = uieditfield(app.PrepareC3DfileUIFigure, 'text');
            app.detectFPbyMarker.Value = 'LHEE';
            % Show the figure after all components are created
            app.PrepareC3DfileUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = processC3D

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PrepareC3DfileUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PrepareC3DfileUIFigure)
        end
    end
end